classdef conceptDemoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        Tree                            matlab.ui.container.Tree
        FactoryNode                     matlab.ui.container.TreeNode
        Motor1Node                      matlab.ui.container.TreeNode
        Bearing11Node                   matlab.ui.container.TreeNode
        Bearing12Node                   matlab.ui.container.TreeNode
        Motor2Node                      matlab.ui.container.TreeNode
        Bearing21Node                   matlab.ui.container.TreeNode
        Bearing22Node                   matlab.ui.container.TreeNode
        Motor3Node                      matlab.ui.container.TreeNode
        Bearing31Node                   matlab.ui.container.TreeNode
        Bearing32Node                   matlab.ui.container.TreeNode
        TabGroupMotor                   matlab.ui.container.TabGroup
        Motor1Tab                       matlab.ui.container.Tab
        TabGroupB1                      matlab.ui.container.TabGroup
        Bearing11Tab                    matlab.ui.container.Tab
        UIAxesB11                       matlab.ui.control.UIAxes
        Bearing12Tab                    matlab.ui.container.Tab
        UIAxesB12                       matlab.ui.control.UIAxes
        UIAxesM1                        matlab.ui.control.UIAxes
        BearingSetupPanel_2             matlab.ui.container.Panel
        RollerDiameterEditField_2Label  matlab.ui.control.Label
        RollerDiameterEditField_2       matlab.ui.control.NumericEditField
        ContactAngleDegreeEditField_2Label  matlab.ui.control.Label
        ContactAngleDegreeEditField_2   matlab.ui.control.NumericEditField
        NumberofElementsEditField_2Label  matlab.ui.control.Label
        NumberofElementsEditField_2     matlab.ui.control.NumericEditField
        SideDropDown_2Label             matlab.ui.control.Label
        SideDropDown_2                  matlab.ui.control.DropDown
        PitchDiameterEditField_2Label   matlab.ui.control.Label
        PitchDiameterEditField_2        matlab.ui.control.NumericEditField
        CommandsPanel_2                 matlab.ui.container.Panel
        AnalyzeButton_2                 matlab.ui.control.Button
        Motor2Tab                       matlab.ui.container.Tab
        TabGroupB2                      matlab.ui.container.TabGroup
        Bearing21Tab                    matlab.ui.container.Tab
        UIAxesB21                       matlab.ui.control.UIAxes
        Bearing22Tab                    matlab.ui.container.Tab
        UIAxesB22                       matlab.ui.control.UIAxes
        UIAxesM2                        matlab.ui.control.UIAxes
        BearingSetupPanel_3             matlab.ui.container.Panel
        RollerDiameterEditField_3Label  matlab.ui.control.Label
        RollerDiameterEditField_3       matlab.ui.control.NumericEditField
        ContactAngleDegreeEditField_3Label  matlab.ui.control.Label
        ContactAngleDegreeEditField_3   matlab.ui.control.NumericEditField
        NumberofElementsEditField_3Label  matlab.ui.control.Label
        NumberofElementsEditField_3     matlab.ui.control.NumericEditField
        SideDropDown_3Label             matlab.ui.control.Label
        SideDropDown_3                  matlab.ui.control.DropDown
        PitchDiameterEditField_3Label   matlab.ui.control.Label
        PitchDiameterEditField_3        matlab.ui.control.NumericEditField
        CommandsPanel_3                 matlab.ui.container.Panel
        AnalyzeButton_3                 matlab.ui.control.Button
        Motor3Tab                       matlab.ui.container.Tab
        TabGroupB3                      matlab.ui.container.TabGroup
        Bearing31Tab                    matlab.ui.container.Tab
        UIAxesB31                       matlab.ui.control.UIAxes
        Bearing32Tab                    matlab.ui.container.Tab
        UIAxesB32                       matlab.ui.control.UIAxes
        UIAxesM3                        matlab.ui.control.UIAxes
        BearingSetupPanel_4             matlab.ui.container.Panel
        RollerDiameterEditField_4Label  matlab.ui.control.Label
        RollerDiameterEditField_4       matlab.ui.control.NumericEditField
        ContactAngleDegreeEditField_4Label  matlab.ui.control.Label
        ContactAngleDegreeEditField_4   matlab.ui.control.NumericEditField
        NumberofElementsEditField_4Label  matlab.ui.control.Label
        NumberofElementsEditField_4     matlab.ui.control.NumericEditField
        SideDropDown_4Label             matlab.ui.control.Label
        SideDropDown_4                  matlab.ui.control.DropDown
        PitchDiameterEditField_4Label   matlab.ui.control.Label
        PitchDiameterEditField_4        matlab.ui.control.NumericEditField
        CommandsPanel_4                 matlab.ui.container.Panel
        AnalyzeButton_4                 matlab.ui.control.Button
        InfoTextAreaLabel               matlab.ui.control.Label
        InfoTextArea                    matlab.ui.control.TextArea
    end


    properties (Access = private)
        images % Videos that would be played
        videos % Videos that would be played
    end

    methods (Access = private)
    
        function LoadVideos(app)
            app.videos.bearing.default = VideoReader('BallBearing.mp4');
            app.videos.bearing.norm = VideoReader('BallBearingNormal.mp4');
            app.videos.bearing.inner = VideoReader('BallBearingInnerRace.mp4');
            app.videos.bearing.outer = VideoReader('BallBearingOuterRace.mp4');
            app.videos.motor.default = VideoReader('FullMotorRunning.mp4');
            app.videos.motor.norm = VideoReader('FullMotorNormal.mp4');
            app.videos.motor.inner = VideoReader('FullMotorInnerRace.mp4');
            app.videos.motor.outer = VideoReader('FullMotorOuterRace.mp4');
        end

        function LoadImages(app)
            app.images.motor = imread('FullMotorRunning.png');
            app.images.bearing = imread('BallBearing.png');
        end
        
        function PlayVideo(app, uiAxes, vidObj)
            vidObj.CurrentTime = 0.0;
            while hasFrame(vidObj)
                vidFrame = readFrame(vidObj);
                image(vidFrame, 'Parent', uiAxes);
                uiAxes.Visible = 'off';
                pause(1/vidObj.FrameRate);
            end
        end
    
        function DefaultDisplay(app)
            app.videos.M1 = app.videos.motor.default;
            app.videos.M2 = app.videos.motor.default;
            app.videos.M3 = app.videos.motor.default;
            app.videos.B11 = app.videos.bearing.default;
            app.videos.B12 = app.videos.bearing.default;
            app.videos.B21 = app.videos.bearing.default;
            app.videos.B22 = app.videos.bearing.default;
            app.videos.B31 = app.videos.bearing.default;
            app.videos.B32 = app.videos.bearing.default;
            
            imshow(app.images.motor, 'Parent', app.UIAxesM1);
            imshow(app.images.bearing, 'Parent', app.UIAxesB11);
            imshow(app.images.bearing, 'Parent', app.UIAxesB12);
            
            imshow(app.images.motor, 'Parent', app.UIAxesM2);
            imshow(app.images.bearing, 'Parent', app.UIAxesB21);
            imshow(app.images.bearing, 'Parent', app.UIAxesB22);
            
            imshow(app.images.motor, 'Parent', app.UIAxesM3);
            imshow(app.images.bearing, 'Parent', app.UIAxesB31);
            imshow(app.images.bearing, 'Parent', app.UIAxesB32);
        end
    
        function DefaultTabs(app)
            app.TabGroupMotor.SelectedTab = app.Motor1Tab;
            app.TabGroupB1.SelectedTab = app.Bearing11Tab;
        end
    
        function Init(app)
            LoadImages(app);
            LoadVideos(app);
            DefaultTabs(app);
            DefaultDisplay(app);
        end

        function PlaySelectedVideo(app, text)
            if (strcmp(text, 'Motor 1'))
                app.TabGroupMotor.SelectedTab = app.Motor1Tab;
                PlayVideo(app, app.UIAxesM1, app.videos.M1);
            elseif (strcmp(text, 'Bearing 1.1'))
                app.TabGroupMotor.SelectedTab = app.Motor1Tab;
                app.TabGroupB1.SelectedTab = app.Bearing11Tab;
                PlayVideo(app, app.UIAxesB11, app.videos.B11);
            elseif (strcmp(text, 'Bearing 1.2'))
                app.TabGroupMotor.SelectedTab = app.Motor1Tab;
                app.TabGroupB1.SelectedTab = app.Bearing12Tab;
                PlayVideo(app, app.UIAxesB12, app.videos.B12);
            elseif (strcmp(text, 'Motor 2'))
                app.TabGroupMotor.SelectedTab = app.Motor2Tab;
                PlayVideo(app, app.UIAxesM2, app.videos.M2);
            elseif (strcmp(text, 'Bearing 2.1'))
                app.TabGroupMotor.SelectedTab = app.Motor2Tab;
                app.TabGroupB2.SelectedTab = app.Bearing21Tab;
                PlayVideo(app, app.UIAxesB21, app.videos.B21);
            elseif (strcmp(text, 'Bearing 2.2'))
                app.TabGroupMotor.SelectedTab = app.Motor2Tab;
                app.TabGroupB2.SelectedTab = app.Bearing22Tab;
                PlayVideo(app, app.UIAxesB22, app.videos.B22);
            elseif (strcmp(text, 'Motor 3'))
                app.TabGroupMotor.SelectedTab = app.Motor3Tab;
                PlayVideo(app, app.UIAxesM3, app.videos.M3);
            elseif (strcmp(text, 'Bearing 3.1'))
                app.TabGroupMotor.SelectedTab = app.Motor3Tab;
                app.TabGroupB3.SelectedTab = app.Bearing31Tab;
                PlayVideo(app, app.UIAxesB31, app.videos.B31);
            elseif (strcmp(text, 'Bearing 3.2'))
                app.TabGroupMotor.SelectedTab = app.Motor3Tab;
                app.TabGroupB3.SelectedTab = app.Bearing32Tab;
                PlayVideo(app, app.UIAxesB32, app.videos.B32);
            end
        end
        
        function Analyze(app)
            app.videos.M1 = app.videos.motor.norm;
            app.videos.B11 = app.videos.bearing.norm;
            app.videos.B12 = app.videos.bearing.norm;
            
            app.videos.M2 = app.videos.motor.inner;
            app.videos.B21 = app.videos.bearing.norm;
            app.videos.B22 = app.videos.bearing.inner;
            
            app.videos.M3 = app.videos.motor.outer;
            app.videos.B31 = app.videos.bearing.outer;
            app.videos.B32 = app.videos.bearing.norm;
            
            PlaySelectedVideo(app, app.TabGroupB1.SelectedTab.Title);            
            PlaySelectedVideo(app, app.TabGroupMotor.SelectedTab.Title);            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Init(app);
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            PlaySelectedVideo(app, app.Tree.SelectedNodes.Text);
        end

        % Selection change function: TabGroupMotor
        function TabGroupMotorSelectionChanged(app, event)
            PlaySelectedVideo(app, app.TabGroupMotor.SelectedTab.Title);
        end

        % Selection change function: TabGroupB1
        function TabGroupB1SelectionChanged(app, event)
            PlaySelectedVideo(app, app.TabGroupB1.SelectedTab.Title);
        end

        % Selection change function: TabGroupB2
        function TabGroupB2SelectionChanged(app, event)
            PlaySelectedVideo(app, app.TabGroupB2.SelectedTab.Title);
        end

        % Selection change function: TabGroupB3
        function TabGroupB3SelectionChanged(app, event)
            PlaySelectedVideo(app, app.TabGroupB3.SelectedTab.Title);
        end

        % Button pushed function: AnalyzeButton_2
        function AnalyzeButton_2Pushed(app, event)
            Analyze(app);
        end

        % Button pushed function: AnalyzeButton_3
        function AnalyzeButton_3Pushed(app, event)
            Analyze(app);
        end

        % Button pushed function: AnalyzeButton_4
        function AnalyzeButton_4Pushed(app, event)
            Analyze(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.FactoryNode = uitreenode(app.Tree);
            app.FactoryNode.Text = 'Factory';
            app.Motor1Node = uitreenode(app.FactoryNode);
            app.Motor1Node.Text = 'Motor 1';
            app.Bearing11Node = uitreenode(app.Motor1Node);
            app.Bearing11Node.Text = 'Bearing 1.1';
            app.Bearing12Node = uitreenode(app.Motor1Node);
            app.Bearing12Node.Text = 'Bearing 1.2';
            app.Motor2Node = uitreenode(app.FactoryNode);
            app.Motor2Node.Text = 'Motor 2';
            app.Bearing21Node = uitreenode(app.Motor2Node);
            app.Bearing21Node.Text = 'Bearing 2.1';
            app.Bearing22Node = uitreenode(app.Motor2Node);
            app.Bearing22Node.Text = 'Bearing 2.2';
            app.Motor3Node = uitreenode(app.FactoryNode);
            app.Motor3Node.Text = 'Motor 3';
            app.Bearing31Node = uitreenode(app.Motor3Node);
            app.Bearing31Node.Text = 'Bearing 3.1';
            app.Bearing32Node = uitreenode(app.Motor3Node);
            app.Bearing32Node.Text = 'Bearing 3.2';
            app.TabGroupMotor = uitabgroup(app.UIFigure);
            app.TabGroupMotor.SelectionChangedFcn = createCallbackFcn(app, @TabGroupMotorSelectionChanged, true);
            app.Motor1Tab = uitab(app.TabGroupMotor);
            app.Motor1Tab.Title = 'Motor 1';
            app.TabGroupB1 = uitabgroup(app.Motor1Tab);
            app.TabGroupB1.SelectionChangedFcn = createCallbackFcn(app, @TabGroupB1SelectionChanged, true);
            app.Bearing11Tab = uitab(app.TabGroupB1);
            app.Bearing11Tab.Title = 'Bearing 1.1';
            app.UIAxesB11 = uiaxes(app.Bearing11Tab);
            app.Bearing12Tab = uitab(app.TabGroupB1);
            app.Bearing12Tab.Title = 'Bearing 1.2';
            app.UIAxesB12 = uiaxes(app.Bearing12Tab);
            app.UIAxesM1 = uiaxes(app.Motor1Tab);
            app.BearingSetupPanel_2 = uipanel(app.Motor1Tab);
            app.BearingSetupPanel_2.Title = 'Bearing Setup';
            app.RollerDiameterEditField_2Label = uilabel(app.BearingSetupPanel_2);
            app.RollerDiameterEditField_2Label.Text = 'Roller Diameter';
            app.RollerDiameterEditField_2 = uieditfield(app.BearingSetupPanel_2, 'numeric');
            app.RollerDiameterEditField_2.Value = 0.235;
            app.ContactAngleDegreeEditField_2Label = uilabel(app.BearingSetupPanel_2);
            app.ContactAngleDegreeEditField_2Label.Text = 'Contact Angle (Degree)';
            app.ContactAngleDegreeEditField_2 = uieditfield(app.BearingSetupPanel_2, 'numeric');
            app.NumberofElementsEditField_2Label = uilabel(app.BearingSetupPanel_2);
            app.NumberofElementsEditField_2Label.Text = 'Number of Elements';
            app.NumberofElementsEditField_2 = uieditfield(app.BearingSetupPanel_2, 'numeric');
            app.NumberofElementsEditField_2.ValueDisplayFormat = '%.0f';
            app.NumberofElementsEditField_2.Value = 8;
            app.SideDropDown_2Label = uilabel(app.BearingSetupPanel_2);
            app.SideDropDown_2Label.Text = 'Side';
            app.SideDropDown_2 = uidropdown(app.BearingSetupPanel_2);
            app.SideDropDown_2.Items = {'Inner', 'Outer'};
            app.SideDropDown_2.Value = 'Outer';
            app.PitchDiameterEditField_2Label = uilabel(app.BearingSetupPanel_2);
            app.PitchDiameterEditField_2Label.Text = 'Pitch Diameter';
            app.PitchDiameterEditField_2 = uieditfield(app.BearingSetupPanel_2, 'numeric');
            app.PitchDiameterEditField_2.Value = 1.245;
            app.CommandsPanel_2 = uipanel(app.Motor1Tab);
            app.CommandsPanel_2.Title = 'Commands';
            app.AnalyzeButton_2 = uibutton(app.CommandsPanel_2, 'push');
            app.AnalyzeButton_2.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_2Pushed, true);
            app.AnalyzeButton_2.Text = 'Analyze';
            app.Motor2Tab = uitab(app.TabGroupMotor);
            app.Motor2Tab.Title = 'Motor 2';
            app.TabGroupB2 = uitabgroup(app.Motor2Tab);
            app.TabGroupB2.SelectionChangedFcn = createCallbackFcn(app, @TabGroupB2SelectionChanged, true);
            app.Bearing21Tab = uitab(app.TabGroupB2);
            app.Bearing21Tab.Title = 'Bearing 2.1';
            app.UIAxesB21 = uiaxes(app.Bearing21Tab);
            app.Bearing22Tab = uitab(app.TabGroupB2);
            app.Bearing22Tab.Title = 'Bearing 2.2';
            app.UIAxesB22 = uiaxes(app.Bearing22Tab);
            app.UIAxesM2 = uiaxes(app.Motor2Tab);
            app.BearingSetupPanel_3 = uipanel(app.Motor2Tab);
            app.BearingSetupPanel_3.Title = 'Bearing Setup';
            app.RollerDiameterEditField_3Label = uilabel(app.BearingSetupPanel_3);
            app.RollerDiameterEditField_3Label.Text = 'Roller Diameter';
            app.RollerDiameterEditField_3 = uieditfield(app.BearingSetupPanel_3, 'numeric');
            app.RollerDiameterEditField_3.Value = 0.235;
            app.ContactAngleDegreeEditField_3Label = uilabel(app.BearingSetupPanel_3);
            app.ContactAngleDegreeEditField_3Label.Text = 'Contact Angle (Degree)';
            app.ContactAngleDegreeEditField_3 = uieditfield(app.BearingSetupPanel_3, 'numeric');
            app.NumberofElementsEditField_3Label = uilabel(app.BearingSetupPanel_3);
            app.NumberofElementsEditField_3Label.Text = 'Number of Elements';
            app.NumberofElementsEditField_3 = uieditfield(app.BearingSetupPanel_3, 'numeric');
            app.NumberofElementsEditField_3.ValueDisplayFormat = '%.0f';
            app.NumberofElementsEditField_3.Value = 8;
            app.SideDropDown_3Label = uilabel(app.BearingSetupPanel_3);
            app.SideDropDown_3Label.Text = 'Side';
            app.SideDropDown_3 = uidropdown(app.BearingSetupPanel_3);
            app.SideDropDown_3.Items = {'Inner', 'Outer'};
            app.SideDropDown_3.Value = 'Outer';
            app.PitchDiameterEditField_3Label = uilabel(app.BearingSetupPanel_3);
            app.PitchDiameterEditField_3Label.Text = 'Pitch Diameter';
            app.PitchDiameterEditField_3 = uieditfield(app.BearingSetupPanel_3, 'numeric');
            app.PitchDiameterEditField_3.Value = 1.245;
            app.CommandsPanel_3 = uipanel(app.Motor2Tab);
            app.CommandsPanel_3.Title = 'Commands';
            app.AnalyzeButton_3 = uibutton(app.CommandsPanel_3, 'push');
            app.AnalyzeButton_3.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_3Pushed, true);
            app.AnalyzeButton_3.Text = 'Analyze';
            app.Motor3Tab = uitab(app.TabGroupMotor);
            app.Motor3Tab.Title = 'Motor 3';
            app.TabGroupB3 = uitabgroup(app.Motor3Tab);
            app.TabGroupB3.SelectionChangedFcn = createCallbackFcn(app, @TabGroupB3SelectionChanged, true);
            app.Bearing31Tab = uitab(app.TabGroupB3);
            app.Bearing31Tab.Title = 'Bearing 3.1';
            app.UIAxesB31 = uiaxes(app.Bearing31Tab);
            app.Bearing32Tab = uitab(app.TabGroupB3);
            app.Bearing32Tab.Title = 'Bearing 3.2';
            app.UIAxesB32 = uiaxes(app.Bearing32Tab);
            app.UIAxesM3 = uiaxes(app.Motor3Tab);
            app.BearingSetupPanel_4 = uipanel(app.Motor3Tab);
            app.BearingSetupPanel_4.Title = 'Bearing Setup';
            app.RollerDiameterEditField_4Label = uilabel(app.BearingSetupPanel_4);
            app.RollerDiameterEditField_4Label.Text = 'Roller Diameter';
            app.RollerDiameterEditField_4 = uieditfield(app.BearingSetupPanel_4, 'numeric');
            app.RollerDiameterEditField_4.Value = 0.235;
            app.ContactAngleDegreeEditField_4Label = uilabel(app.BearingSetupPanel_4);
            app.ContactAngleDegreeEditField_4Label.Text = 'Contact Angle (Degree)';
            app.ContactAngleDegreeEditField_4 = uieditfield(app.BearingSetupPanel_4, 'numeric');
            app.NumberofElementsEditField_4Label = uilabel(app.BearingSetupPanel_4);
            app.NumberofElementsEditField_4Label.Text = 'Number of Elements';
            app.NumberofElementsEditField_4 = uieditfield(app.BearingSetupPanel_4, 'numeric');
            app.NumberofElementsEditField_4.ValueDisplayFormat = '%.0f';
            app.NumberofElementsEditField_4.Value = 8;
            app.SideDropDown_4Label = uilabel(app.BearingSetupPanel_4);
            app.SideDropDown_4Label.Text = 'Side';
            app.SideDropDown_4 = uidropdown(app.BearingSetupPanel_4);
            app.SideDropDown_4.Items = {'Inner', 'Outer'};
            app.SideDropDown_4.Value = 'Outer';
            app.PitchDiameterEditField_4Label = uilabel(app.BearingSetupPanel_4);
            app.PitchDiameterEditField_4Label.Text = 'Pitch Diameter';
            app.PitchDiameterEditField_4 = uieditfield(app.BearingSetupPanel_4, 'numeric');
            app.PitchDiameterEditField_4.Value = 1.245;
            app.CommandsPanel_4 = uipanel(app.Motor3Tab);
            app.CommandsPanel_4.Title = 'Commands';
            app.AnalyzeButton_4 = uibutton(app.CommandsPanel_4, 'push');
            app.AnalyzeButton_4.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButton_4Pushed, true);
            app.AnalyzeButton_4.Text = 'Analyze';
            app.InfoTextAreaLabel = uilabel(app.UIFigure);
            app.InfoTextAreaLabel.Text = 'Info';
            app.InfoTextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = conceptDemoApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end